classdef SIGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        INPanel                         matlab.ui.container.Panel
        ObiektPanel                     matlab.ui.container.Panel
        WzmocnienieEditFieldLabel       matlab.ui.control.Label
        K                               matlab.ui.control.NumericEditField
        OpnienieEditFieldLabel          matlab.ui.control.Label
        tau                             matlab.ui.control.NumericEditField
        PierwszastaaczasowaEditFieldLabel  matlab.ui.control.Label
        T1                              matlab.ui.control.NumericEditField
        DrugastaaczasowaEditFieldLabel  matlab.ui.control.Label
        T2                              matlab.ui.control.NumericEditField
        SymulacjaPanel                  matlab.ui.container.Panel
        WarunekzatrzymaniaDropDownLabel  matlab.ui.control.Label
        iterCond                        matlab.ui.control.DropDown
        MetodaselekcjiDropDownLabel     matlab.ui.control.Label
        method                          matlab.ui.control.DropDown
        MaksymalnawartonastawyLabel     matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        IloscosobnikowLabel             matlab.ui.control.Label
        iloscOsob                       matlab.ui.control.NumericEditField
        CzuloscmetodyLabel              matlab.ui.control.Label
        czuloscMetody                   matlab.ui.control.NumericEditField
        LiczbaIter                      matlab.ui.control.Label
        IterOrTimeValue                 matlab.ui.control.NumericEditField
        CzassymulacjiEditFieldLabel     matlab.ui.control.Label
        simTime                         matlab.ui.control.NumericEditField
        LiczbabitwosobnikaEditFieldLabel  matlab.ui.control.Label
        bitNumber                       matlab.ui.control.NumericEditField
        labelCostam                     matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        OUTPanel                        matlab.ui.container.Panel
        WyznaczonenastawyregulatoraPanel  matlab.ui.container.Panel
        PLabel                          matlab.ui.control.Label
        ILabel                          matlab.ui.control.Label
        DLabel                          matlab.ui.control.Label
        LabelP                          matlab.ui.control.Label
        LabelI                          matlab.ui.control.Label
        LabelD                          matlab.ui.control.Label
        WartocifunkcjiceluPanel         matlab.ui.container.Panel
        WartofunkcjiceluLabel           matlab.ui.control.Label
        PrzeregulowanieLabel            matlab.ui.control.Label
        CzasregulacjiLabel              matlab.ui.control.Label
        labelPunkty                     matlab.ui.control.Label
        labelPrzeregulowanie            matlab.ui.control.Label
        labelCzasRegulacji              matlab.ui.control.Label
        UchybLabel                      matlab.ui.control.Label
        KryteriumcakoweLabel            matlab.ui.control.Label
        labelUchyb                      matlab.ui.control.Label
        labelKrytCalk                   matlab.ui.control.Label
        PrzebiegprocesuuczeniaPanel     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        running = false;
        model;
        sensitivity;
        bits;
        iter;
        pop;
        iterOrTime;
    end
    
    
    methods (Access = private)
        
        function results = start(app, f)
            
            p = StartPopGUI(app.pop, app.bits);
            k = DecPopGUI(p);
            
            tic;
            
            if app.iterOrTime == "iter"
                i = 1;
            elseif app.iterOrTime == "time"
                
                i = toc;
            end
            z = 1;
            
            data = zeros([1, app.iter]) * nan;
            j = zeros([1, app.iter]) * nan;
            axis(app.UIAxes, [0, inf, 0, inf]);
            
            while i <= app.iter && app.Switch.Value == "Start"
                
                
                pause(0.1);
                if app.Switch.Value == "Stop"
                    app.running = false;
                    break;
                end
                 nextGen = feval(f, k, app.model, app.sensitivity);
                pairs = Pairing2GUI(nextGen, app.pop, app.bits);
                mut = MutationGUI(pairs, app.bits);
                k = mut;
                pause(0)
                [score, prze, czR, uchyb, krytCalk] = PIDOptimFuncGUI(k(1, :), app.model);
                app.labelPunkty.Text = string(score);
                app.labelPrzeregulowanie.Text = string(prze) + "%";
                app.labelCzasRegulacji.Text = string(czR);
                app.labelUchyb.Text = string(uchyb);
                app.labelKrytCalk.Text = string(krytCalk);
                
                app.LabelP.Text = string(k(1,1));
                app.LabelI.Text = string(k(1,2));
                app.LabelD.Text = string(k(1,3));
                
                data(z) = score;
                j(z) = z-1;
                app.labelPunkty.Text = string(score);
                plot(app.UIAxes, j, data);
                axis(app.UIAxes, [0, inf, 0, inf]);
                grid(app.UIAxes);
                app.Label_2.Text = "iteracja numer: " + string(z);
                app.Label_2.Text = app.Label_2.Text + " Czas " + string(toc);
                
                if app.iterOrTime == "iter"
                    i = i + 1;
                elseif app.iterOrTime == "time"
                    i = toc;
                end
                z = z + 1;
                pause(0.1);
                if app.Switch.Value == "Stop"
                    app.running = false;
                    break;
                end
                
            end
            
            if app.running == true
                app.Label_2.Text = "Iteracja numer: " + string(z-1);
                app.Label_2.Text = app.Label_2.Text + " Czas " + string(toc);
                app.labelCostam.Text = "PRZYCZYNA ZATRZYMANIA ALGORYTMU:"+newline+"Operacja zakonczona";
                app.Switch.Value = "Stop";
                app.running = false;
            elseif app.running == false
                app.labelCostam.Text = "PRZYCZYNA ZATRZYMANIA ALGORYTMU:"+newline+"Zatrzymanie ręczne";
            end
        end
        
        function results = startClicked(app)
            if app.running == false
                app.running = true;
                app.labelCostam.Text = "Obliczanie";
                app.model = [app.K.Value, app.T1.Value, app.T2.Value, app.tau.Value, app.simTime.Value];
                app.bits = app.bitNumber.Value;
                app.sensitivity = app.czuloscMetody.Value;
                app.pop = app.iloscOsob.Value;
                app.iter = app.IterOrTimeValue.Value;
                
                
                if app.iterCond.Value == "Liczba iteracji"
                    app.iterOrTime = "iter";
                elseif app.iterCond.Value == "Czas"
                    app.iterOrTime = "time";
                end
                pause(0);
                if app.method.Value == "Ruletka"
                    app.start('RandomSelectionGUI');
                elseif app.method.Value == "Rangowa"
                    app.start('RankSelectionGUI');
                end
            end
        end
        
        function results = stopClicked(app)
            if app.running
                app.running = false;
                app.labelCostam.Text = "PRZYCZYNA ZATRZYMANIA ALGORYTMU:"+newline+"Zatrzymanie ręczne";
                pause(0);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: bitNumber
        function bitNumberValueChanged(app, event)
            value = app.bitNumber.Value;
            app.Label.Text = string(((2.^value)-1)/100);
        end

        % Value changed function: iterCond
        function iterCondValueChanged(app, event)
            value = app.iterCond.Value;
            if value == "Liczba iteracji"
                app.LiczbaIter.Text = "Liczba iteracji";
            elseif value == "Czas"
                app.LiczbaIter.Text = "Czas iteracji";
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == "Start"
                app.startClicked();
            elseif value == "Stop"
                app.stopClicked();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.INPanel = uipanel(app.UIFigure);
            app.INPanel.Title = 'IN';
            app.ObiektPanel = uipanel(app.INPanel);
            app.ObiektPanel.Title = 'Obiekt';
            app.WzmocnienieEditFieldLabel = uilabel(app.ObiektPanel);
            app.WzmocnienieEditFieldLabel.Text = 'Wzmocnienie';
            app.K = uieditfield(app.ObiektPanel, 'numeric');
            app.K.Limits = [0 Inf];
            app.K.Value = 1;
            app.OpnienieEditFieldLabel = uilabel(app.ObiektPanel);
            app.OpnienieEditFieldLabel.Text = 'Opóźnienie';
            app.tau = uieditfield(app.ObiektPanel, 'numeric');
            app.tau.Limits = [0 Inf];
            app.tau.Value = 1;
            app.PierwszastaaczasowaEditFieldLabel = uilabel(app.ObiektPanel);
            app.PierwszastaaczasowaEditFieldLabel.Text = 'Pierwsza stała czasowa';
            app.T1 = uieditfield(app.ObiektPanel, 'numeric');
            app.T1.Limits = [0 Inf];
            app.T1.Value = 5;
            app.DrugastaaczasowaEditFieldLabel = uilabel(app.ObiektPanel);
            app.DrugastaaczasowaEditFieldLabel.Text = 'Druga stała czasowa';
            app.T2 = uieditfield(app.ObiektPanel, 'numeric');
            app.T2.Limits = [0 Inf];
            app.T2.Value = 9;
            app.SymulacjaPanel = uipanel(app.INPanel);
            app.SymulacjaPanel.Title = 'Symulacja';
            app.WarunekzatrzymaniaDropDownLabel = uilabel(app.SymulacjaPanel);
            app.WarunekzatrzymaniaDropDownLabel.Text = 'Warunek zatrzymania';
            app.iterCond = uidropdown(app.SymulacjaPanel);
            app.iterCond.Items = {'Liczba iteracji', 'Czas'};
            app.iterCond.ValueChangedFcn = createCallbackFcn(app, @iterCondValueChanged, true);
            app.iterCond.Value = 'Liczba iteracji';
            app.MetodaselekcjiDropDownLabel = uilabel(app.SymulacjaPanel);
            app.MetodaselekcjiDropDownLabel.Text = 'Metoda selekcji';
            app.method = uidropdown(app.SymulacjaPanel);
            app.method.Items = {'Ruletka', 'Rangowa'};
            app.method.Value = 'Ruletka';
            app.MaksymalnawartonastawyLabel = uilabel(app.SymulacjaPanel);
            app.MaksymalnawartonastawyLabel.Text = 'Maksymalna wartość nastawy:';
            app.Label = uilabel(app.SymulacjaPanel);
            app.Label.Text = '10,23';
            app.IloscosobnikowLabel = uilabel(app.SymulacjaPanel);
            app.IloscosobnikowLabel.Text = 'Ilosc osobnikow';
            app.iloscOsob = uieditfield(app.SymulacjaPanel, 'numeric');
            app.iloscOsob.Limits = [10 Inf];
            app.iloscOsob.Value = 20;
            app.CzuloscmetodyLabel = uilabel(app.SymulacjaPanel);
            app.CzuloscmetodyLabel.Tooltip = {'Ruletka - im większa wartość tym mniejsza szansa dla najgorszych i większa dla najlepszych osobników na krzyżowanie'; 'Rangowa - procent najlepszych osobników wybranych do krzyżowania (zawsze minimum dwóch)'};
            app.CzuloscmetodyLabel.Text = 'Czulosc metody';
            app.czuloscMetody = uieditfield(app.SymulacjaPanel, 'numeric');
            app.czuloscMetody.Limits = [0 100];
            app.czuloscMetody.Value = 100;
            app.LiczbaIter = uilabel(app.SymulacjaPanel);
            app.LiczbaIter.Text = 'Liczba iteracji:';
            app.IterOrTimeValue = uieditfield(app.SymulacjaPanel, 'numeric');
            app.IterOrTimeValue.Limits = [1 Inf];
            app.IterOrTimeValue.Value = 10;
            app.CzassymulacjiEditFieldLabel = uilabel(app.SymulacjaPanel);
            app.CzassymulacjiEditFieldLabel.Text = 'Czas symulacji';
            app.simTime = uieditfield(app.SymulacjaPanel, 'numeric');
            app.simTime.Limits = [0 Inf];
            app.simTime.Value = 100;
            app.LiczbabitwosobnikaEditFieldLabel = uilabel(app.SymulacjaPanel);
            app.LiczbabitwosobnikaEditFieldLabel.Text = 'Liczba bitów osobnika';
            app.bitNumber = uieditfield(app.SymulacjaPanel, 'numeric');
            app.bitNumber.Limits = [1 Inf];
            app.bitNumber.ValueChangedFcn = createCallbackFcn(app, @bitNumberValueChanged, true);
            app.bitNumber.Value = 10;
            app.labelCostam = uilabel(app.INPanel);
            app.labelCostam.Text = '';
            app.Label_2 = uilabel(app.INPanel);
            app.Label_2.Text = '';
            app.Switch = uiswitch(app.INPanel, 'slider');
            app.Switch.Items = {'Stop', 'Start'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Stop';
            app.OUTPanel = uipanel(app.UIFigure);
            app.OUTPanel.Title = 'OUT';
            app.WyznaczonenastawyregulatoraPanel = uipanel(app.OUTPanel);
            app.WyznaczonenastawyregulatoraPanel.Title = 'Wyznaczone nastawy regulatora';
            app.PLabel = uilabel(app.WyznaczonenastawyregulatoraPanel);
            app.PLabel.Text = 'P:';
            app.ILabel = uilabel(app.WyznaczonenastawyregulatoraPanel);
            app.ILabel.Text = 'I:';
            app.DLabel = uilabel(app.WyznaczonenastawyregulatoraPanel);
            app.DLabel.Text = 'D:';
            app.LabelP = uilabel(app.WyznaczonenastawyregulatoraPanel);
            app.LabelP.Text = '';
            app.LabelI = uilabel(app.WyznaczonenastawyregulatoraPanel);
            app.LabelI.Text = '';
            app.LabelD = uilabel(app.WyznaczonenastawyregulatoraPanel);
            app.LabelD.Text = '';
            app.WartocifunkcjiceluPanel = uipanel(app.OUTPanel);
            app.WartocifunkcjiceluPanel.Title = 'Wartości funkcji celu';
            app.WartofunkcjiceluLabel = uilabel(app.WartocifunkcjiceluPanel);
            app.WartofunkcjiceluLabel.Text = 'Wartość funkcji celu:';
            app.PrzeregulowanieLabel = uilabel(app.WartocifunkcjiceluPanel);
            app.PrzeregulowanieLabel.Text = 'Przeregulowanie:';
            app.CzasregulacjiLabel = uilabel(app.WartocifunkcjiceluPanel);
            app.CzasregulacjiLabel.Text = 'Czas regulacji:';
            app.labelPunkty = uilabel(app.WartocifunkcjiceluPanel);
            app.labelPunkty.Text = '';
            app.labelPrzeregulowanie = uilabel(app.WartocifunkcjiceluPanel);
            app.labelPrzeregulowanie.Text = '';
            app.labelCzasRegulacji = uilabel(app.WartocifunkcjiceluPanel);
            app.labelCzasRegulacji.Text = '';
            app.UchybLabel = uilabel(app.WartocifunkcjiceluPanel);
            app.UchybLabel.Text = 'Uchyb:';
            app.KryteriumcakoweLabel = uilabel(app.WartocifunkcjiceluPanel);
            app.KryteriumcakoweLabel.Text = 'Kryterium całkowe:';
            app.labelUchyb = uilabel(app.WartocifunkcjiceluPanel);
            app.labelUchyb.Text = '';
            app.labelKrytCalk = uilabel(app.WartocifunkcjiceluPanel);
            app.labelKrytCalk.Text = '';
            app.PrzebiegprocesuuczeniaPanel = uipanel(app.OUTPanel);
            app.PrzebiegprocesuuczeniaPanel.Title = 'Przebieg procesu uczenia';
            app.UIAxes = uiaxes(app.PrzebiegprocesuuczeniaPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 1];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end